classdef app_pressure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IndividualpressurecontrolPanel  matlab.ui.container.Panel
        ButtonGroup_10                  matlab.ui.container.ButtonGroup
        LowButton_10                    matlab.ui.control.ToggleButton
        HighButton_10                   matlab.ui.control.ToggleButton
        PatchButton_10                  matlab.ui.control.ToggleButton
        AirButton_10                    matlab.ui.control.ToggleButton
        CleanButton_10                  matlab.ui.control.ToggleButton
        Manipulator10CheckBox           matlab.ui.control.CheckBox
        ButtonGroup_9                   matlab.ui.container.ButtonGroup
        LowButton_9                     matlab.ui.control.ToggleButton
        HighButton_9                    matlab.ui.control.ToggleButton
        PatchButton_9                   matlab.ui.control.ToggleButton
        AirButton_9                     matlab.ui.control.ToggleButton
        CleanButton_9                   matlab.ui.control.ToggleButton
        Manipulator9CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_8                   matlab.ui.container.ButtonGroup
        LowButton_8                     matlab.ui.control.ToggleButton
        HighButton_8                    matlab.ui.control.ToggleButton
        PatchButton_8                   matlab.ui.control.ToggleButton
        AirButton_8                     matlab.ui.control.ToggleButton
        CleanButton_8                   matlab.ui.control.ToggleButton
        Manipulator8CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_7                   matlab.ui.container.ButtonGroup
        LowButton_7                     matlab.ui.control.ToggleButton
        HighButton_7                    matlab.ui.control.ToggleButton
        PatchButton_7                   matlab.ui.control.ToggleButton
        AirButton_7                     matlab.ui.control.ToggleButton
        CleanButton_7                   matlab.ui.control.ToggleButton
        Manipulator7CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_6                   matlab.ui.container.ButtonGroup
        LowButton_6                     matlab.ui.control.ToggleButton
        HighButton_6                    matlab.ui.control.ToggleButton
        PatchButton_6                   matlab.ui.control.ToggleButton
        AirButton_6                     matlab.ui.control.ToggleButton
        CleanButton_6                   matlab.ui.control.ToggleButton
        Manipulator6CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        LowButton_5                     matlab.ui.control.ToggleButton
        HighButton_5                    matlab.ui.control.ToggleButton
        PatchButton_5                   matlab.ui.control.ToggleButton
        AirButton_5                     matlab.ui.control.ToggleButton
        CleanButton_5                   matlab.ui.control.ToggleButton
        Manipulator5CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        LowButton_4                     matlab.ui.control.ToggleButton
        HighButton_4                    matlab.ui.control.ToggleButton
        PatchButton_4                   matlab.ui.control.ToggleButton
        AirButton_4                     matlab.ui.control.ToggleButton
        CleanButton_4                   matlab.ui.control.ToggleButton
        Manipulator4CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        LowButton_3                     matlab.ui.control.ToggleButton
        HighButton_3                    matlab.ui.control.ToggleButton
        PatchButton_3                   matlab.ui.control.ToggleButton
        AirButton_3                     matlab.ui.control.ToggleButton
        CleanButton_3                   matlab.ui.control.ToggleButton
        Manipulator3CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        LowButton_2                     matlab.ui.control.ToggleButton
        HighButton_2                    matlab.ui.control.ToggleButton
        PatchButton_2                   matlab.ui.control.ToggleButton
        AirButton_2                     matlab.ui.control.ToggleButton
        CleanButton_2                   matlab.ui.control.ToggleButton
        Manipulator2CheckBox            matlab.ui.control.CheckBox
        ButtonGroup_1                   matlab.ui.container.ButtonGroup
        LowButton_1                     matlab.ui.control.ToggleButton
        HighButton_1                    matlab.ui.control.ToggleButton
        PatchButton_1                   matlab.ui.control.ToggleButton
        AirButton_1                     matlab.ui.control.ToggleButton
        CleanButton_1                   matlab.ui.control.ToggleButton
        Manipulator1CheckBox            matlab.ui.control.CheckBox
        PressurecontrolofselectedPanel  matlab.ui.container.Panel
        UnselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        LowButton                       matlab.ui.control.Button
        AirButton                       matlab.ui.control.Button
        StartcleaningButton             matlab.ui.control.Button
        CleanButton                     matlab.ui.control.Button
        MovementcontrolofselectedPanel  matlab.ui.container.Panel
        HomeINButton                    matlab.ui.control.Button
        HomeOUTButton                   matlab.ui.control.Button
        ZeroXYZButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StepButton                      matlab.ui.control.Button
        MovetozeroButton                matlab.ui.control.Button
        inmLabel                        matlab.ui.control.Label
        stepsize                        matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        s                    %array storing the serial objects of manipulators
        a                    %object containing the arduino board
        setup                %container for setup.mat variables
        n                    %number of manipulators
        vacuum1              %pin number for vacuum valve 1
        vacuum2              %pin number for vacuum valve 2
        pressure             %pin number for pressure valve
        pipette_valves       %variable containing valve_wiring
        mv_state             %variable for function to indicate which state should be set
        selected             %manipulators being changed
       
        checkbox             %array holding checkboxes, checkbox.Value indicates states
        low                  %array holding checkboxes of low, app.low.Value indicates states
        high                 %array holding checkboxes of high, app.high.Value indicates states
        mouth                %array holding checkboxes of mouth, app.mouth.Value indicates states
        air                  %array holding checkboxes of air, app.air.Value indicates states
        cleaning             %array holding checkboxes of cleaning, app.cleaning.Value indicates states
        togglebutton         %array holding toggle button groups
    end

    methods (Access = public)
    
        function safety_check(app,selected)    %only allow one manipulator to be in HIGH or PATCH mode
          if app.high(selected).Value == 1 || app.mouth(selected).Value == 1
            for i = 1:app.setup.manipulator_number
                if i ~= selected && (app.high(i).Value == 1 || app.mouth(i).Value ==1)
                    app.air(i).Value=1;
                    setvalve(app.a,app.pipette_valves,i,'Air');    %if any other manipulator is on high/mouth, turn it to air
                end
             end
          end
        end 
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %load setup.mat variables
            app.setup = load('setup.mat');
            app.n = app.setup.manipulator_number;    %n=number of manipulators
            
            %load arduino
            delete(instrfindall);                    %delete instruments from workspace
            [app.a,app.pipette_valves,app.vacuum1,app.vacuum2,app.pressure] = setup_arduinomega(app);
            
            %load manipulators
            [app.s] = manipulator_setup(app);
            
            %assign states of all button and checkboxes to one designated variable
            [app.checkbox,app.low,app.high,app.mouth,app.air,app.cleaning,app.togglebutton]=group_states(app);
            
            %hide UI elements for unused manipulators
            for i = 1:10         %10 represents max number of pipettes for this GUI
                if i > app.n
                app.togglebutton(i).Visible = 'off';
                end
            end
        end

        % Button pushed function: HomeINButton
        function HomeINButtonPushed(app, event)
            setlow(app);
            manipulator_homein(app);
        end

        % Button pushed function: HomeOUTButton
        function HomeOUTButtonPushed(app, event)
            setair(app);
            manipulator_homeout(app);
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            for i = 1:app.n
                app.checkbox(i).Value = 1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(instrfindall);
            delete(app);            
        end

        % Button pushed function: UnselectallButton
        function UnselectallButtonPushed(app, event)
            for i = 1:app.n
                app.checkbox(i).Value = 0;
            end
        end

        % Button pushed function: StartcleaningButton
        function cleanmove(app, event)
            manipulator_move_pressure(app);
            
            %% after clean set cleaned pipette to low
            for i = 1:app.n
                if app.cleaning(i).Value == 1
                    app.low(i).Value = app.cleaning(i).Value;
                    setvalve(app.a,app.pipette_valves,i,'Low');
                end
            end
        end

        % Button pushed function: AirButton
        function setair(app, event)
            for i = 1:app.n
                if app.checkbox(i).Value == 1  %only perform if checkbox is checked
                    app.air(i).Value = app.checkbox(i).Value;
                    setvalve(app.a,app.pipette_valves,i,'Air');
                end
            end         
        end

        % Button pushed function: CleanButton
        function setclean(app, event)
            for i = 1:app.n
                if app.checkbox(i).Value == 1  %only perform if checkbox is checked
                    app.cleaning(i).Value = app.checkbox(i).Value;
                    setvalve(app.a,app.pipette_valves,i,'Clean');
                end
            end
        end

        % Button pushed function: LowButton
        function setlow(app, event)
            for i = 1:app.n
                if app.checkbox(i).Value == 1  %only perform if checkbox is checked, do not unselect
                    app.low(i).Value = app.checkbox(i).Value;
                    setvalve(app.a,app.pipette_valves,i,'Low');
                end
            end
        end

        % Button pushed function: StepButton
        function step(app, event)
            manipulator_step(app);
        end

        % Button pushed function: StopButton
        function stop(app, event)
            manipulator_stop(app);
        end

        % Callback function
        function togglebutton_1(app, event)
            safety_check(app,1);
            setvalve(app.a,app.pipette_valves,1,app.ButtonGroup_1.SelectedObject.Text); %argumente: arduino,verschaltung,manipulatornummer,text von toggle button
        end

        % Selection changed function: ButtonGroup_10
        function togglebutton_10(app, event)
            safety_check(app,10);
            setvalve(app.a,app.pipette_valves,10,app.ButtonGroup_10.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_2
        function togglebutton_2(app, event)
            safety_check(app,2);
            setvalve(app.a,app.pipette_valves,2,app.ButtonGroup_2.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_3
        function togglebutton_3(app, event)
            safety_check(app,3);
            setvalve(app.a,app.pipette_valves,3,app.ButtonGroup_3.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_4
        function togglebutton_4(app, event)
            safety_check(app,4);
            setvalve(app.a,app.pipette_valves,4,app.ButtonGroup_4.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_5
        function togglebutton_5(app, event)
            safety_check(app,5);
            setvalve(app.a,app.pipette_valves,5,app.ButtonGroup_5.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_6
        function togglebutton_6(app, event)
            safety_check(app,6);
            setvalve(app.a,app.pipette_valves,6,app.ButtonGroup_6.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_7
        function togglebutton_7(app, event)
            safety_check(app,7);
            setvalve(app.a,app.pipette_valves,7,app.ButtonGroup_7.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_8
        function togglebutton_8(app, event)
            safety_check(app,8);
            setvalve(app.a,app.pipette_valves,8,app.ButtonGroup_8.SelectedObject.Text);
        end

        % Selection changed function: ButtonGroup_9
        function togglebutton_9(app, event)
            safety_check(app,9);
            setvalve(app.a,app.pipette_valves,9,app.ButtonGroup_9.SelectedObject.Text);
        end

        % Button pushed function: MovetozeroButton
        function movezero(app, event)
            manipulator_movezero(app);
        end

        % Button pushed function: ZeroXYZButton
        function zeroxyz(app, event)
            manipulator_zeroxyz(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IndividualpressurecontrolPanel = uipanel(app.UIFigure);
            app.IndividualpressurecontrolPanel.Title = 'Individual pressure control';
            app.ButtonGroup_10 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_10.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_10, true);
            app.LowButton_10 = uitogglebutton(app.ButtonGroup_10);
            app.LowButton_10.Text = 'Low';
            app.HighButton_10 = uitogglebutton(app.ButtonGroup_10);
            app.HighButton_10.Text = 'High';
            app.PatchButton_10 = uitogglebutton(app.ButtonGroup_10);
            app.PatchButton_10.Text = 'Patch';
            app.AirButton_10 = uitogglebutton(app.ButtonGroup_10);
            app.AirButton_10.Text = 'Air';
            app.AirButton_10.Value = true;
            app.CleanButton_10 = uitogglebutton(app.ButtonGroup_10);
            app.CleanButton_10.Text = 'Clean';
            app.Manipulator10CheckBox = uicheckbox(app.ButtonGroup_10);
            app.Manipulator10CheckBox.Text = 'Manipulator 10';
            app.ButtonGroup_9 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_9.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_9, true);
            app.LowButton_9 = uitogglebutton(app.ButtonGroup_9);
            app.LowButton_9.Text = 'Low';
            app.HighButton_9 = uitogglebutton(app.ButtonGroup_9);
            app.HighButton_9.Text = 'High';
            app.PatchButton_9 = uitogglebutton(app.ButtonGroup_9);
            app.PatchButton_9.Text = 'Patch';
            app.AirButton_9 = uitogglebutton(app.ButtonGroup_9);
            app.AirButton_9.Text = 'Air';
            app.AirButton_9.Value = true;
            app.CleanButton_9 = uitogglebutton(app.ButtonGroup_9);
            app.CleanButton_9.Text = 'Clean';
            app.Manipulator9CheckBox = uicheckbox(app.ButtonGroup_9);
            app.Manipulator9CheckBox.Text = 'Manipulator 9';
            app.ButtonGroup_8 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_8.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_8, true);
            app.LowButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.LowButton_8.Text = 'Low';
            app.HighButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.HighButton_8.Text = 'High';
            app.PatchButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.PatchButton_8.Text = 'Patch';
            app.AirButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.AirButton_8.Text = 'Air';
            app.AirButton_8.Value = true;
            app.CleanButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.CleanButton_8.Text = 'Clean';
            app.Manipulator8CheckBox = uicheckbox(app.ButtonGroup_8);
            app.Manipulator8CheckBox.Text = 'Manipulator 8';
            app.ButtonGroup_7 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_7.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_7, true);
            app.LowButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.LowButton_7.Text = 'Low';
            app.HighButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.HighButton_7.Text = 'High';
            app.PatchButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.PatchButton_7.Text = 'Patch';
            app.AirButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.AirButton_7.Text = 'Air';
            app.AirButton_7.Value = true;
            app.CleanButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.CleanButton_7.Text = 'Clean';
            app.Manipulator7CheckBox = uicheckbox(app.ButtonGroup_7);
            app.Manipulator7CheckBox.Text = 'Manipulator 7';
            app.ButtonGroup_6 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_6.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_6, true);
            app.LowButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.LowButton_6.Text = 'Low';
            app.HighButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.HighButton_6.Text = 'High';
            app.PatchButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.PatchButton_6.Text = 'Patch';
            app.AirButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.AirButton_6.Text = 'Air';
            app.AirButton_6.Value = true;
            app.CleanButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.CleanButton_6.Text = 'Clean';
            app.Manipulator6CheckBox = uicheckbox(app.ButtonGroup_6);
            app.Manipulator6CheckBox.Text = 'Manipulator 6';
            app.ButtonGroup_5 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_5, true);
            app.LowButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.LowButton_5.Text = 'Low';
            app.HighButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.HighButton_5.Text = 'High';
            app.PatchButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.PatchButton_5.Text = 'Patch';
            app.AirButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.AirButton_5.Text = 'Air';
            app.AirButton_5.Value = true;
            app.CleanButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.CleanButton_5.Text = 'Clean';
            app.Manipulator5CheckBox = uicheckbox(app.ButtonGroup_5);
            app.Manipulator5CheckBox.Text = 'Manipulator 5';
            app.ButtonGroup_4 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_4, true);
            app.LowButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.LowButton_4.Text = 'Low';
            app.HighButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.HighButton_4.Text = 'High';
            app.PatchButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.PatchButton_4.Text = 'Patch';
            app.AirButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.AirButton_4.Text = 'Air';
            app.AirButton_4.Value = true;
            app.CleanButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.CleanButton_4.Text = 'Clean';
            app.Manipulator4CheckBox = uicheckbox(app.ButtonGroup_4);
            app.Manipulator4CheckBox.Text = 'Manipulator 4';
            app.ButtonGroup_3 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_3, true);
            app.LowButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.LowButton_3.Text = 'Low';
            app.HighButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.HighButton_3.Text = 'High';
            app.PatchButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.PatchButton_3.Text = 'Patch';
            app.AirButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.AirButton_3.Text = 'Air';
            app.AirButton_3.Value = true;
            app.CleanButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.CleanButton_3.Text = 'Clean';
            app.Manipulator3CheckBox = uicheckbox(app.ButtonGroup_3);
            app.Manipulator3CheckBox.Text = 'Manipulator 3';
            app.ButtonGroup_2 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_2, true);
            app.LowButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.LowButton_2.Text = 'Low';
            app.HighButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.HighButton_2.Text = 'High';
            app.PatchButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.PatchButton_2.Text = 'Patch';
            app.AirButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.AirButton_2.Text = 'Air';
            app.AirButton_2.Value = true;
            app.CleanButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.CleanButton_2.Text = 'Clean';
            app.Manipulator2CheckBox = uicheckbox(app.ButtonGroup_2);
            app.Manipulator2CheckBox.Text = 'Manipulator 2';
            app.ButtonGroup_1 = uibuttongroup(app.IndividualpressurecontrolPanel);
            app.ButtonGroup_1.SelectionChangedFcn = createCallbackFcn(app, @togglebutton_1, true);
            app.LowButton_1 = uitogglebutton(app.ButtonGroup_1);
            app.LowButton_1.Text = 'Low';
            app.HighButton_1 = uitogglebutton(app.ButtonGroup_1);
            app.HighButton_1.Text = 'High';
            app.PatchButton_1 = uitogglebutton(app.ButtonGroup_1);
            app.PatchButton_1.Text = 'Patch';
            app.AirButton_1 = uitogglebutton(app.ButtonGroup_1);
            app.AirButton_1.Text = 'Air';
            app.AirButton_1.Value = true;
            app.CleanButton_1 = uitogglebutton(app.ButtonGroup_1);
            app.CleanButton_1.Text = 'Clean';
            app.Manipulator1CheckBox = uicheckbox(app.ButtonGroup_1);
            app.Manipulator1CheckBox.Text = 'Manipulator1';
            app.PressurecontrolofselectedPanel = uipanel(app.UIFigure);
            app.PressurecontrolofselectedPanel.Title = 'Pressure control of selected';
            app.UnselectallButton = uibutton(app.PressurecontrolofselectedPanel, 'push');
            app.UnselectallButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectallButtonPushed, true);
            app.UnselectallButton.Text = 'Unselect all';
            app.SelectallButton = uibutton(app.PressurecontrolofselectedPanel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.LowButton = uibutton(app.PressurecontrolofselectedPanel, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @setlow, true);
            app.LowButton.Text = 'Low';
            app.AirButton = uibutton(app.PressurecontrolofselectedPanel, 'push');
            app.AirButton.ButtonPushedFcn = createCallbackFcn(app, @setair, true);
            app.AirButton.Text = 'Air';
            app.StartcleaningButton = uibutton(app.PressurecontrolofselectedPanel, 'push');
            app.StartcleaningButton.ButtonPushedFcn = createCallbackFcn(app, @cleanmove, true);
            app.StartcleaningButton.Text = 'Start cleaning';
            app.CleanButton = uibutton(app.PressurecontrolofselectedPanel, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @setclean, true);
            app.CleanButton.Text = 'Clean';
            app.MovementcontrolofselectedPanel = uipanel(app.UIFigure);
            app.MovementcontrolofselectedPanel.Title = 'Movement control of selected';
            app.HomeINButton = uibutton(app.MovementcontrolofselectedPanel, 'push');
            app.HomeINButton.ButtonPushedFcn = createCallbackFcn(app, @HomeINButtonPushed, true);
            app.HomeINButton.Text = 'Home IN/Low';
            app.HomeOUTButton = uibutton(app.MovementcontrolofselectedPanel, 'push');
            app.HomeOUTButton.ButtonPushedFcn = createCallbackFcn(app, @HomeOUTButtonPushed, true);
            app.HomeOUTButton.Text = 'Home Out/Air';
            app.ZeroXYZButton = uibutton(app.MovementcontrolofselectedPanel, 'push');
            app.ZeroXYZButton.ButtonPushedFcn = createCallbackFcn(app, @zeroxyz, true);
            app.ZeroXYZButton.Text = 'Zero XYZ';
            app.StopButton = uibutton(app.MovementcontrolofselectedPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.StopButton.Text = 'Stop';
            app.StepButton = uibutton(app.MovementcontrolofselectedPanel, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @step, true);
            app.StepButton.Text = 'Step';
            app.MovetozeroButton = uibutton(app.MovementcontrolofselectedPanel, 'push');
            app.MovetozeroButton.ButtonPushedFcn = createCallbackFcn(app, @movezero, true);
            app.MovetozeroButton.Text = 'Move to zero';
            app.inmLabel = uilabel(app.MovementcontrolofselectedPanel);
            app.inmLabel.Text = 'in µm';
            app.stepsize = uieditfield(app.MovementcontrolofselectedPanel, 'numeric');
            app.stepsize.Value = 2000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_pressure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end